classdef Calculadora < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Resolver                matlab.ui.control.Button
        VALOR2EditFieldLabel_2  matlab.ui.control.Label
        Valor2                  matlab.ui.control.EditField
        Operacion               matlab.ui.control.DropDown
        OperacionDropDownLabel  matlab.ui.control.Label
        ResultadoLabel          matlab.ui.control.Label
        Resultado               matlab.ui.control.EditField
        Valor1                  matlab.ui.control.EditField
        VALOR1EditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Resolver
        function ResolverButtonPushed(app, event)
        valor1 = str2double(app.Valor1.Value);
        valor2 = str2double(app.Valor2.Value);
        op = app.Operacion.Value;
    
        switch op
            case 'Suma'
                res = valor1 + valor2;
            case 'Resta'
                res = valor1 - valor2;
            case 'Multiplicacion'
                res = valor1 * valor2;
            case 'Division'
                res = valor1 / valor2;
            case 'Raiz enesima'
                res = nthroot(valor1, valor2);
            case 'Potenciacion'
                res = valor1^valor2;
            case 'Logaritmo base n'
                res = log(valor2) / log(valor1);
        end
    
        app.Resultado.Value = num2str(res);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VALOR1EditFieldLabel = uilabel(app.UIFigure);
            app.VALOR1EditFieldLabel.Text = 'VALOR 1';
            app.Valor1 = uieditfield(app.UIFigure, 'text');
            app.Resultado = uieditfield(app.UIFigure, 'text');
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.OperacionDropDownLabel = uilabel(app.UIFigure);
            app.OperacionDropDownLabel.Text = 'Operacion';
            app.Operacion = uidropdown(app.UIFigure);
            app.Operacion.Items = {'Suma', 'Resta', 'Multiplicacion', 'Division', 'Raiz enecima', 'Potenciacion', 'Logaritmo base n'};
            app.Operacion.Value = 'Suma';
            app.Valor2 = uieditfield(app.UIFigure, 'text');
            app.VALOR2EditFieldLabel_2 = uilabel(app.UIFigure);
            app.VALOR2EditFieldLabel_2.Text = 'VALOR 2';
            app.Resolver = uibutton(app.UIFigure, 'push');
            app.Resolver.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.Resolver.Text = 'Resolver';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculadora

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end