classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Formula               matlab.ui.control.EditField
        FormulaLabel          matlab.ui.control.Label
        Graficar              matlab.ui.control.Button
        LimitesuperiorYLabel  matlab.ui.control.Label
        limsY                 matlab.ui.control.Spinner
        LimiteinferiorYLabel  matlab.ui.control.Label
        limiY                 matlab.ui.control.Spinner
        EspaciadoLabel        matlab.ui.control.Label
        Espaciado             matlab.ui.control.Spinner
        LimitesuperiorXLabel  matlab.ui.control.Label
        limsX                 matlab.ui.control.Spinner
        LimiteinferiorXLabel  matlab.ui.control.Label
        limiX                 matlab.ui.control.Spinner
        GraficaSup3D          matlab.ui.control.UIAxes
        GraficaMa3D           matlab.ui.control.UIAxes
        GraficaSupyCon        matlab.ui.control.UIAxes
        GraficaCon            matlab.ui.control.UIAxes
        GraficaSup            matlab.ui.control.UIAxes
        GraficaMa             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Graficar
        function GraficarPushed(app, event)
            formula = app.Formula.Value;

            LimiteInferiorX = app.limiX.Value;
            LimiteSuperiorX = app.limsX.Value;

            LimiteInferiorY = app.limiY.Value;
            LimiteSuperiorY = app.limsY.Value;

            Espaciamiento = app.Espaciado.Value;

            x = LimiteInferiorX:Espaciamiento:LimiteSuperiorX;
            y = LimiteInferiorY:Espaciamiento:LimiteSuperiorY;

            [X, Y] = meshgrid(x, y);
            
            Z = eval(formula);

            mesh(app.GraficaMa, X, Z);

            surf(app.GraficaSup, X, Z);

            mesh(app.GraficaMa3D, X, Y, Z);

            surf(app.GraficaSup3D, X, Y, Z);

            contour3(app.GraficaCon, X, Y, Z);
            
            surfc(app.GraficaSupyCon, X, Y, Z);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GraficaMa = uiaxes(app.UIFigure);
            title(app.GraficaMa, 'Grafica de Malla')
            xlabel(app.GraficaMa, 'X')
            ylabel(app.GraficaMa, 'Y')
            zlabel(app.GraficaMa, 'Z')
            app.GraficaSup = uiaxes(app.UIFigure);
            title(app.GraficaSup, 'Grafica de Superficie')
            xlabel(app.GraficaSup, 'X')
            ylabel(app.GraficaSup, 'Y')
            zlabel(app.GraficaSup, 'Z')
            app.GraficaCon = uiaxes(app.UIFigure);
            title(app.GraficaCon, 'Grafica 3D de Contorno')
            xlabel(app.GraficaCon, 'X')
            ylabel(app.GraficaCon, 'Y')
            zlabel(app.GraficaCon, 'Z')
            app.GraficaSupyCon = uiaxes(app.UIFigure);
            title(app.GraficaSupyCon, 'Grafica de Superficie y Contorno')
            xlabel(app.GraficaSupyCon, 'X')
            ylabel(app.GraficaSupyCon, 'Y')
            zlabel(app.GraficaSupyCon, 'Z')
            app.GraficaMa3D = uiaxes(app.UIFigure);
            title(app.GraficaMa3D, 'Grafica de Malla 3D')
            xlabel(app.GraficaMa3D, 'X')
            ylabel(app.GraficaMa3D, 'Y')
            zlabel(app.GraficaMa3D, 'Z')
            app.GraficaSup3D = uiaxes(app.UIFigure);
            title(app.GraficaSup3D, 'Grafica de Superficie 3D')
            xlabel(app.GraficaSup3D, 'X')
            ylabel(app.GraficaSup3D, 'Y')
            zlabel(app.GraficaSup3D, 'Z')
            app.limiX = uispinner(app.UIFigure);
            app.LimiteinferiorXLabel = uilabel(app.UIFigure);
            app.LimiteinferiorXLabel.Text = 'Limite inferior X';
            app.limsX = uispinner(app.UIFigure);
            app.LimitesuperiorXLabel = uilabel(app.UIFigure);
            app.LimitesuperiorXLabel.Text = 'Limite superior X';
            app.Espaciado = uispinner(app.UIFigure);
            app.Espaciado.Step = 0.01;
            app.Espaciado.Limits = [0 Inf];
            app.EspaciadoLabel = uilabel(app.UIFigure);
            app.EspaciadoLabel.Text = 'Espaciado';
            app.limiY = uispinner(app.UIFigure);
            app.LimiteinferiorYLabel = uilabel(app.UIFigure);
            app.LimiteinferiorYLabel.Text = 'Limite inferior Y';
            app.limsY = uispinner(app.UIFigure);
            app.LimitesuperiorYLabel = uilabel(app.UIFigure);
            app.LimitesuperiorYLabel.Text = 'Limite superior Y';
            app.Graficar = uibutton(app.UIFigure, 'push');
            app.Graficar.ButtonPushedFcn = createCallbackFcn(app, @GraficarPushed, true);
            app.Graficar.Text = 'Graficar';
            app.FormulaLabel = uilabel(app.UIFigure);
            app.FormulaLabel.Text = 'Formula';
            app.Formula = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end